classdef oscilloscopehw2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        VoltscaleSlider       matlab.ui.control.Slider
        VoltscaleSliderLabel  matlab.ui.control.Label
        TimescaleSlider       matlab.ui.control.Slider
        TimescaleSliderLabel  matlab.ui.control.Label
        Channel2SquareLabel   matlab.ui.control.Label
        Channel1SinLabel      matlab.ui.control.Label
        amplitudeKnob         matlab.ui.control.Knob
        amplitudeKnobLabel    matlab.ui.control.Label
        VerticalKnob          matlab.ui.control.Knob
        VerticalKnobLabel     matlab.ui.control.Label
        Vertical2Knob         matlab.ui.control.Knob
        Vertical2KnobLabel    matlab.ui.control.Label
        amplitude2Knob        matlab.ui.control.Knob
        amplitude2KnobLabel   matlab.ui.control.Label
        Frequency2Knob        matlab.ui.control.Knob
        Frequency2KnobLabel   matlab.ui.control.Label
        FrequencyKnob         matlab.ui.control.Knob
        FrequencyKnobLabel    matlab.ui.control.Label
        powerSwitch           matlab.ui.control.Switch
        powerSwitchLabel      matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        LampLabel             matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Frequency2Knob, Frequency2Knob, FrequencyKnob,
        % 
        % ...and 10 other components
        function powerSwitchValueChanged(app, event)
               
                vert1 = app.VerticalKnob.Value; % to move the analog signal up and down    
                vert2 = app.Vertical2Knob.Value; % to move the square signal up and down
                freq  = app.FrequencyKnob.Value;  % value of analog wave frequancy knob
                ampl  = app.amplitudeKnob.Value; % value of analog wave amplitude knowb 
                time  = -10:0.01:app.TimescaleSlider.Value;               % length of waves at time
                %duration  = app.TimescaleSlider.Value;
                sin_wave  = ampl*sin(2*pi*freq*time)+vert1; % sin wave (analog) equation 
                
                freq2  = app.Frequency2Knob.Value; % value of square wave frequancy knob
                ampl2  = app.amplitude2Knob.Value;  % value of square wave amplitude knob
                
               square_wave = ampl2*square(2*pi*freq2*time)+vert2; % square wave  equation


         if app.powerSwitch.Value == "on" % condition of switch botton on on
                
                app.Lamp.Color=[0 1 0]; % to make color of lamp green
                                
               plot(app.UIAxes,time,sin_wave,'c',time,square_wave,'r'); % plotting waves on UIAxes oscilloscope 
               
                %xlim(app.UIAxes, [1, 10]);   % domain of x axis of oscilloscope 
                %ylim(app.UIAxes, [-15, 15]);   % range of y axis of oscilloscope 
                xvalue = app.TimescaleSlider.Value;
                app.UIAxes.XLim = [0 , xvalue];
                yvalue = app.VoltscaleSlider.Value;
                app.UIAxes.YLim = [0 , yvalue];
                grid(app.UIAxes, "on");   % to make 'grid on ' on UIAxes
                legend(app.UIAxes,'sin','square'); % to show name of waves on oscilloscope 


         else app.powerSwitch.Value == "off" % condition of switch botton off
                app.Lamp.Color=[1 0 0]; % to make lamp color red
                plot(app.UIAxes,0,0);  % to close  oscilloscope  when the power switch off
        end
        end

        % Callback function
        function FrequencyKnobValueChanged(app, event)
value = app.TimescaleSlider.Value;

        end

        % Callback function
        function ThetaKnobValueChanged(app, event)
          
        end

        % Callback function
        function amplitudeSliderValueChanged(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
         
        end

        % Callback function
        function amplitudeKnobValueChanging(app, event)
            
        end

        % Callback function
        function amplitudeKnobValueChanged(app, event)
            
        end

        % Value changed function: TimescaleSlider
        function TimescaleSliderValueChanged(app, event)
            value = app.TimescaleSlider.Value;
                        app.UIAxes.XLim = [0 value];

        end

        % Value changed function: VoltscaleSlider
        function VoltscaleSliderValueChanged(app, event)
            value = app.VoltscaleSlider.Value;
                        app.UIAxes.YLim = [0 value];
                        app.UIAxes.YLim = [-value value];
                        app.UIAxes.YTick = linspace(-value,value,8);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Oscilloscope')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Volt')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.powerSwitchLabel = uilabel(app.UIFigure);
            app.powerSwitchLabel.Text = 'power';
            app.powerSwitch = uiswitch(app.UIFigure, 'slider');
            app.powerSwitch.Items = {'off', 'on'};
            app.powerSwitch.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.powerSwitch.Value = 'off';
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.Limits = [0 25];
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.Frequency2KnobLabel = uilabel(app.UIFigure);
            app.Frequency2KnobLabel.Text = 'Frequency2';
            app.Frequency2Knob = uiknob(app.UIFigure, 'continuous');
            app.Frequency2Knob.Limits = [0 25];
            app.Frequency2Knob.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.Frequency2Knob.ValueChangingFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.amplitude2KnobLabel = uilabel(app.UIFigure);
            app.amplitude2KnobLabel.Text = 'amplitude2';
            app.amplitude2Knob = uiknob(app.UIFigure, 'continuous');
            app.amplitude2Knob.Limits = [0 20];
            app.amplitude2Knob.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.amplitude2Knob.ValueChangingFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.Vertical2KnobLabel = uilabel(app.UIFigure);
            app.Vertical2KnobLabel.Text = 'Vertical2';
            app.Vertical2Knob = uiknob(app.UIFigure, 'continuous');
            app.Vertical2Knob.Limits = [-10 10];
            app.Vertical2Knob.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.Vertical2Knob.ValueChangingFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.VerticalKnobLabel = uilabel(app.UIFigure);
            app.VerticalKnobLabel.Text = 'Vertical';
            app.VerticalKnob = uiknob(app.UIFigure, 'continuous');
            app.VerticalKnob.Limits = [-10 10];
            app.VerticalKnob.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.VerticalKnob.ValueChangingFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.amplitudeKnobLabel = uilabel(app.UIFigure);
            app.amplitudeKnobLabel.Text = 'amplitude';
            app.amplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.amplitudeKnob.Limits = [0 20];
            app.amplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.amplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @powerSwitchValueChanged, true);
            app.Channel1SinLabel = uilabel(app.UIFigure);
            app.Channel1SinLabel.Text = 'Channel 1 (Sin)';
            app.Channel2SquareLabel = uilabel(app.UIFigure);
            app.Channel2SquareLabel.Text = 'Channel 2 (Square)';
            app.TimescaleSliderLabel = uilabel(app.UIFigure);
            app.TimescaleSliderLabel.Text = 'Time scale';
            app.TimescaleSlider = uislider(app.UIFigure);
            app.TimescaleSlider.ValueChangedFcn = createCallbackFcn(app, @TimescaleSliderValueChanged, true);
            app.TimescaleSlider.Value = 2;
            app.VoltscaleSliderLabel = uilabel(app.UIFigure);
            app.VoltscaleSliderLabel.Text = 'Volt scale';
            app.VoltscaleSlider = uislider(app.UIFigure);
            app.VoltscaleSlider.ValueChangedFcn = createCallbackFcn(app, @VoltscaleSliderValueChanged, true);
            app.VoltscaleSlider.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscilloscopehw2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end